classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        CostFunctionCoefficientsLabel  matlab.ui.control.Label
        OccupancyDropDown              matlab.ui.control.DropDown
        OccupancyDropDownLabel         matlab.ui.control.Label
        LoadButton                     matlab.ui.control.Button
        TurnsSlider                    matlab.ui.control.Slider
        TurnsSliderLabel               matlab.ui.control.Label
        CongestionSlider               matlab.ui.control.Slider
        CongestionSliderLabel          matlab.ui.control.Label
        DistanceSlider                 matlab.ui.control.Slider
        DistanceSliderLabel            matlab.ui.control.Label
        LayoutEditField                matlab.ui.control.EditField
        LayoutEditFieldLabel           matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        HelpLabel                      matlab.ui.control.Label
        CenterPanel                    matlab.ui.container.Panel
        AxMap                          matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        PathValueLabel                 matlab.ui.control.Label
        CostValueLabel                 matlab.ui.control.Label
        TimeValueLabel                 matlab.ui.control.Label
        PathLabel                      matlab.ui.control.Label
        CostLabel                      matlab.ui.control.Label
        ExecutiontimesLabel            matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Layout  % Name of the layout
        Prob    % The problem object
        Occupancy = "Random"
        OccupancyMatrix
        FinalPathLine
        FinalPathTexts
        HelpText1 = "Press the Load button to initialize the problem with layout and node occupancy. Enter a layout function name, or number of nodes."
        HelpText2 = "Use the mouse to select a start point, and any number of stop points. Press enter when done."
        HelpText3 = "Select the weights for the path finding objective, and press Start."
        HelpText4 = "Load another layout/occupancy, or recalibrate weights and press Start again."
    end
    
    methods (Access = private)
        
        function results = plot_layout(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.HelpLabel.Text = app.HelpText2;

            app.Layout = string(app.LayoutEditField.Value);
            [num, possible] = str2num(app.Layout);
            if length(num)==1 && possible
                app.Layout = num;
            end
            cla(app.AxMap);
            axes(app.AxMap);

            % Using ginput, from:
            % https://www.mathworks.com/matlabcentral/answers/392617-how-can-i-use-ginput-in-app-designer#answer_504862
            % Check Matlab version, throw error if prior to r2020b
            assert(~verLessThan('Matlab', '9.9'), 'ginput not supported prior to Matlab r2020b.')
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

            app.Prob = getProb(app.Layout, [], [], true, app.AxMap, app.Occupancy);
            app.Prob.interval = 0;

            app.UIFigure.HandleVisibility = fhv;        % return original state

            app.HelpLabel.Text = app.HelpText3;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            prob = app.Prob;
            % The local fitness function is the cost of individual paths between two
            % stop points. This is the same as fitness in ordered GA
            prob.fitness_local = @(sol) costFunc( ...
                sol, prob.adj, prob.start, prob.goal, prob.coords, prob.occupancy,...
                0, app.DistanceSlider.Value, app.TurnsSlider.Value, app.CongestionSlider.Value);

            % costAdj = app.DistanceSlider.Value * prob.adj + app.CongestionSlider.Value * prob.occupancy;
            % Cost matrix needed for pair-wise shortest distances between nodes
            % costMatrix = distances(digraph(costAdj));
            % 
            % The fitness function measures how good a candidate solution is. That is,
            % the ordering of stop points
            % fitness = @(sol) sum(costMatrix(sub2ind([prob.N, prob.N], sol(1:end-1), sol(2:end))));
            fitness = @costFuncUnordered;
            
            [stops, cost, timeTaken, prob] = GAVariable(prob, fitness);

            app.TimeValueLabel.Text = compose("%.2f", timeTaken);
            app.CostValueLabel.Text = compose("%.3f", cost);
            app.PathValueLabel.Text = join(string(stops), ", ");
            delete(prob.lines);
            hold(prob.axMap, 'on');
            total_path = [];
            for i=1:length(stops)-1
                [path, ~, ~] = AStar(stops(i), stops(i+1), prob.fitness_local, prob.adj);
                total_path = [total_path, path];
            end
            % highlight(prob.pl, path(1:end-1), path(2:end), 'EdgeColor','black', 'NodeColor','black');
            delete(app.FinalPathLine);
            delete(app.FinalPathTexts);
            app.FinalPathLine = plot(prob.axMap, prob.coords(total_path,1), prob.coords(total_path,2), 'Color','black', 'LineWidth', 2);
            app.FinalPathTexts = text(prob.axMap, prob.coords(stops,1), prob.coords(stops,2), split(num2str(1:length(stops))));
            hold(prob.axMap, 'off');

            app.HelpLabel.Text = app.HelpText4;
        end

        % Drop down opening function: OccupancyDropDown
        function OccupancyDropDownOpening(app, event)
            app.OccupancyDropDown.Items = ["Random"; evalin('base', 'who')];
        end

        % Value changed function: OccupancyDropDown
        function OccupancyDropDownValueChanged(app, event)
            value = app.OccupancyDropDown.Value;
            if value=="Random"
                app.Occupancy = value;
            elseif ~isempty(value)
                app.Occupancy = evalin('base', value);
            end
        end

        % Clicked callback: OccupancyDropDown
        function OccupancyDropDownClicked(app, event)

        end

        % Callback function
        function ResetButtonPushed(app, event)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.HelpLabel = uilabel(app.LeftPanel);
            app.HelpLabel.Text = 'Press the Load button to initialize the problem with layout and node occupancy. Enter a layout function name, or number of nodes.';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LayoutEditFieldLabel = uilabel(app.LeftPanel);
            app.LayoutEditFieldLabel.Text = 'Layout';
            app.LayoutEditField = uieditfield(app.LeftPanel, 'text');
            app.LayoutEditField.Value = 'l1';
            app.DistanceSliderLabel = uilabel(app.LeftPanel);
            app.DistanceSliderLabel.Text = 'Distance';
            app.DistanceSlider = uislider(app.LeftPanel);
            app.DistanceSlider.Limits = [0 1];
            app.DistanceSlider.Value = 1;
            app.CongestionSliderLabel = uilabel(app.LeftPanel);
            app.CongestionSliderLabel.Text = 'Congestion';
            app.CongestionSlider = uislider(app.LeftPanel);
            app.CongestionSlider.Limits = [0 1];
            app.CongestionSlider.Value = 0.5;
            app.TurnsSliderLabel = uilabel(app.LeftPanel);
            app.TurnsSliderLabel.Text = 'Turns';
            app.TurnsSlider = uislider(app.LeftPanel);
            app.TurnsSlider.Limits = [0 1];
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.OccupancyDropDownLabel = uilabel(app.LeftPanel);
            app.OccupancyDropDownLabel.Text = 'Occupancy';
            app.OccupancyDropDown = uidropdown(app.LeftPanel);
            app.OccupancyDropDown.Items = {'Random'};
            app.OccupancyDropDown.DropDownOpeningFcn = createCallbackFcn(app, @OccupancyDropDownOpening, true);
            app.OccupancyDropDown.ValueChangedFcn = createCallbackFcn(app, @OccupancyDropDownValueChanged, true);
            app.OccupancyDropDown.ClickedFcn = createCallbackFcn(app, @OccupancyDropDownClicked, true);
            app.OccupancyDropDown.Value = 'Random';
            app.CostFunctionCoefficientsLabel = uilabel(app.LeftPanel);
            app.CostFunctionCoefficientsLabel.Text = 'Cost Function Coefficients:';
            app.CenterPanel = uipanel(app.GridLayout);
            app.AxMap = uiaxes(app.CenterPanel);
            title(app.AxMap, 'Layout')
            xlabel(app.AxMap, 'X')
            ylabel(app.AxMap, 'Y')
            zlabel(app.AxMap, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.ExecutiontimesLabel = uilabel(app.RightPanel);
            app.ExecutiontimesLabel.Text = 'Execution time (s)';
            app.CostLabel = uilabel(app.RightPanel);
            app.CostLabel.Text = 'Cost';
            app.PathLabel = uilabel(app.RightPanel);
            app.PathLabel.Text = 'Path';
            app.TimeValueLabel = uilabel(app.RightPanel);
            app.TimeValueLabel.Text = '';
            app.CostValueLabel = uilabel(app.RightPanel);
            app.CostValueLabel.Text = '';
            app.PathValueLabel = uilabel(app.RightPanel);
            app.PathValueLabel.Text = '';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'static', 'logo_tsu.gif');
            app.Image2 = uiimage(app.RightPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'static', 'logo_amazon.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end